classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExitImage                       matlab.ui.control.Image
        Label_7                         matlab.ui.control.Label
        sEMGANALYSISTOOLBOXLabel        matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MVCImage                        matlab.ui.control.Image
        MVCNormalisationButton          matlab.ui.control.ToggleButton
        NormalisationLabel              matlab.ui.control.Label
        ImageB                          matlab.ui.control.Image
        ImageA                          matlab.ui.control.Image
        ImageOutlier                    matlab.ui.control.Image
        ImageFastFourrier               matlab.ui.control.Image
        ImageConversion                 matlab.ui.control.Image
        imageImport                     matlab.ui.control.Image
        ProcessingLabel                 matlab.ui.control.Label
        TrackBButton                    matlab.ui.control.ToggleButton
        TrackAButton                    matlab.ui.control.ToggleButton
        OutlierremovalButton            matlab.ui.control.ToggleButton
        FastFourrierButton              matlab.ui.control.ToggleButton
        ConversionButton                matlab.ui.control.ToggleButton
        ImportdataButton                matlab.ui.control.ToggleButton
        TabGroup                        matlab.ui.container.TabGroup
        RAWDATATab                      matlab.ui.container.Tab
        SelectfileButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        YourdataLabel                   matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        CH1Tab                          matlab.ui.container.Tab
        RawDataChannel1                 matlab.ui.control.UIAxes
        CH2Tab                          matlab.ui.container.Tab
        RawDataChannel2                 matlab.ui.control.UIAxes
        CH3Tab                          matlab.ui.container.Tab
        RawDataChannel3                 matlab.ui.control.UIAxes
        CH4Tab                          matlab.ui.container.Tab
        RawDataChannel4                 matlab.ui.control.UIAxes
        CH5Tab                          matlab.ui.container.Tab
        RawDataChannel5                 matlab.ui.control.UIAxes
        mVDATATab                       matlab.ui.container.Tab
        ConverttomVButton               matlab.ui.control.Button
        mVDATALabel                     matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        TabGroup2_2                     matlab.ui.container.TabGroup
        CH1Tab_2                        matlab.ui.container.Tab
        MvDataChannel1                  matlab.ui.control.UIAxes
        CH2Tab_2                        matlab.ui.container.Tab
        MvDataChannel2                  matlab.ui.control.UIAxes
        CH3Tab_2                        matlab.ui.container.Tab
        MvDataChannel3                  matlab.ui.control.UIAxes
        CH4Tab_2                        matlab.ui.container.Tab
        MvDataChannel4                  matlab.ui.control.UIAxes
        CH5Tab_2                        matlab.ui.container.Tab
        MvDataChannel5                  matlab.ui.control.UIAxes
        FFTANALYSISTab                  matlab.ui.container.Tab
        FFTButton                       matlab.ui.control.Button
        TabGroup2_3                     matlab.ui.container.TabGroup
        CH1Tab_3                        matlab.ui.container.Tab
        FFTDataChannel1                 matlab.ui.control.UIAxes
        CH2Tab_3                        matlab.ui.container.Tab
        FFTDataChannel2                 matlab.ui.control.UIAxes
        CH3Tab_3                        matlab.ui.container.Tab
        FFTDataChannel3                 matlab.ui.control.UIAxes
        CH4Tab_6                        matlab.ui.container.Tab
        FFTDataChannel4                 matlab.ui.control.UIAxes
        CH5Tab_3                        matlab.ui.container.Tab
        FFTDataChannel5                 matlab.ui.control.UIAxes
        REMOVEOUTLIERTab                matlab.ui.container.Tab
        HTML2                           matlab.ui.control.HTML
        REMOVEOUTLIERButton             matlab.ui.control.Button
        FillmethodLabel                 matlab.ui.control.Label
        MethodfordetectingoutliersLabel  matlab.ui.control.Label
        FillOutlierOptionDropdown       matlab.ui.control.DropDown
        RemoveOutlierOptionDropdown     matlab.ui.control.DropDown
        OutlierDeviationOptionDropdown  matlab.ui.control.DropDown
        OutlieroptionsLabel             matlab.ui.control.Label
        StandarddeviationLabel          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TabGroup2_4                     matlab.ui.container.TabGroup
        CH1TimedomainTab_4              matlab.ui.container.Tab
        OutlierDataChannel1             matlab.ui.control.UIAxes
        CH2TimedomainTab_4              matlab.ui.container.Tab
        OutlierDataChannel2             matlab.ui.control.UIAxes
        CH3TimedomainTab_4              matlab.ui.container.Tab
        OutlierDataChannel3             matlab.ui.control.UIAxes
        CH4TimedomainTab_4              matlab.ui.container.Tab
        OutlierDataChannel4             matlab.ui.control.UIAxes
        CH5TimedomainTab_4              matlab.ui.container.Tab
        OutlierDataChannel5             matlab.ui.control.UIAxes
        CH1FrequencydomainTab_3         matlab.ui.container.Tab
        OutlierDataChannel1_FD          matlab.ui.control.UIAxes
        CH2FrequencydomainTab_3         matlab.ui.container.Tab
        OutlierDataChannel2_FD          matlab.ui.control.UIAxes
        CH3FrequencydomainTab_3         matlab.ui.container.Tab
        OutlierDataChannel3_FD          matlab.ui.control.UIAxes
        CH4FrequencydomainTab_3         matlab.ui.container.Tab
        OutlierDataChannel4_FD          matlab.ui.control.UIAxes
        CH5FrequencydomainTab_3         matlab.ui.container.Tab
        OutlierDataChannel5_FD          matlab.ui.control.UIAxes
        PROCESSINGTRACKATab             matlab.ui.container.Tab
        HTML3                           matlab.ui.control.HTML
        ExportTrackA                    matlab.ui.control.Button
        Cuttofffrequency2Label          matlab.ui.control.Label
        Cuttofffrequency1Label          matlab.ui.control.Label
        TrackA_FC1                      matlab.ui.control.NumericEditField
        TrackA_FC2                      matlab.ui.control.NumericEditField
        FilterorderLabel                matlab.ui.control.Label
        FilterOrderDropdown             matlab.ui.control.DropDown
        FilterTypeDropdown              matlab.ui.control.DropDown
        ApplyfilterButton               matlab.ui.control.Button
        FiltertypeLabel                 matlab.ui.control.Label
        FilteroptionsLabel              matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        TabGroup2_5                     matlab.ui.container.TabGroup
        CH1TimedomainTab                matlab.ui.container.Tab
        TrackAchannel1                  matlab.ui.control.UIAxes
        CH2TimedomainTab                matlab.ui.container.Tab
        TrackAchannel2                  matlab.ui.control.UIAxes
        CH3TimedomainTab_3              matlab.ui.container.Tab
        TrackAchannel3                  matlab.ui.control.UIAxes
        CH4TimedomainTab                matlab.ui.container.Tab
        TrackAchannel4                  matlab.ui.control.UIAxes
        CH5TimedomainTab                matlab.ui.container.Tab
        TrackAchannel5                  matlab.ui.control.UIAxes
        CH1FrequencydomainTab           matlab.ui.container.Tab
        TrackAchannel1FD                matlab.ui.control.UIAxes
        CH2FrequencydomainTab           matlab.ui.container.Tab
        TrackAchannel2FD                matlab.ui.control.UIAxes
        CH3FrequencydomainTab           matlab.ui.container.Tab
        TrackAchannel3FD                matlab.ui.control.UIAxes
        CH4FrequencydomainTab           matlab.ui.container.Tab
        TrackAchannel4FD                matlab.ui.control.UIAxes
        CH5FrequencydomainTab           matlab.ui.container.Tab
        TrackAchannel5FD                matlab.ui.control.UIAxes
        PROCESSINGTRACKBTab             matlab.ui.container.Tab
        ExportTrackB                    matlab.ui.control.Button
        HTML2_2                         matlab.ui.control.HTML
        ApplyButton                     matlab.ui.control.Button
        TabGroup2_6                     matlab.ui.container.TabGroup
        CH1TimedomainTab_2              matlab.ui.container.Tab
        TrackBchannel1_2                matlab.ui.control.UIAxes
        CH2TimedomainTab_2              matlab.ui.container.Tab
        TrackBchannel2_2                matlab.ui.control.UIAxes
        CH3TimedomainTab                matlab.ui.container.Tab
        TrackBchannel3_2                matlab.ui.control.UIAxes
        CH4TimedomainTab_2              matlab.ui.container.Tab
        TrackBchannel4_2                matlab.ui.control.UIAxes
        CH5TimedomainTab_2              matlab.ui.container.Tab
        TrackBchannel5_2                matlab.ui.control.UIAxes
        CH1FrequencydomainTab_2         matlab.ui.container.Tab
        TrackBchannel1_FD               matlab.ui.control.UIAxes
        CH2FrequencydomainTab_2         matlab.ui.container.Tab
        TrackBchannel2_FD               matlab.ui.control.UIAxes
        CH3FrequencydomainTab_2         matlab.ui.container.Tab
        TrackBchannel3_FD               matlab.ui.control.UIAxes
        CH4FrequencydomainTab_2         matlab.ui.container.Tab
        TrackBchannel4_FD               matlab.ui.control.UIAxes
        CH5FrequencydomainTab_2         matlab.ui.container.Tab
        TrackBchannel5_FD               matlab.ui.control.UIAxes
        WindowsizeLabel                 matlab.ui.control.Label
        WindowSizeTextbox               matlab.ui.control.NumericEditField
        TrackBoptionsLabel              matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        MVCNORMALISATIONTab             matlab.ui.container.Tab
        SELECTMVCFILESButton            matlab.ui.control.Button
        DropDownMVC                     matlab.ui.control.DropDown
        YourdataMVC                     matlab.ui.control.Label
        UITableMVC                      matlab.ui.control.Table
        TabGroup2_7                     matlab.ui.container.TabGroup
        CH1TimedomainTab_3              matlab.ui.container.Tab
        MVCnormalisationChannel1        matlab.ui.control.UIAxes
        CH2TimedomainTab_3              matlab.ui.container.Tab
        MVCnormalisationChannel2        matlab.ui.control.UIAxes
        CH3TimedomainTab_2              matlab.ui.container.Tab
        MVCnormalisationChannel3        matlab.ui.control.UIAxes
        CH4TimedomainTab_3              matlab.ui.container.Tab
        MVCnormalisationChannel4        matlab.ui.control.UIAxes
        CH5TimedomainTab_3              matlab.ui.container.Tab
        MVCnormalisationChannel5        matlab.ui.control.UIAxes
        ExportButton                    matlab.ui.control.Button
        MVCNORMALISATIONButton          matlab.ui.control.Button
    end

    
    properties (Access = private)

%       GLOBAL VARIABLES AND SOME INITIAL VALUES ARE STORED HERE

        mVDataMatrix % Description
        nSeq % Description
        rawChannelData % Description
        FilterType = 'hp'; % Description
        FilterOrder = 1; % Description
        Channel1FFTData % Description
        Channel2FFTData % Description
        Channel3FFTData % Description
        Channel4FFTData % Description
        Channel5FFTData % Description
        f % Description
        fillOutlierOption = "center" % Description
        removeOutlierOption = "median" % Description
        thresholdFactor = 1 % Description
        SettingsUsed = "{Settings:"; % Description
        fs % Description
        SettingsCounter = 1; % Description
        windowSizeTrackB = 1; % Description
        Outlier1Channel1;
        Outlier1Channel2;
        Outlier1Channel3;
        Outlier1Channel4;
        Outlier1Channel5;
        rmsC1;                         
        rmsC2; 
        rmsC3; 
        rmsC4; 
        rmsC5;
        MVCFile1;
        MVCFile2;
        MVCFile3;
        MVCFile4;
        MVCFile5;
        MVC_normalization1;
        MVC_normalization2;
        MVC_normalization3;
        MVC_normalization4;
        MVC_normalization5;
        FilterDataCh1;
        FilterDataCh2;
        FilterDataCh3;
        FilterDataCh4;
        FilterDataCh5;
        channel1MvData;
        channel2MvData;
        channel3MvData;
        channel4MvData;
        channel5MvData;
        MVCdropdownOption = 'Track A Filter';
        legendFilename % Description
    end
    
    methods (Access = private)

%       UI COLOR CONFIGURATION  
               
        function ResetButtonColor(app)
            app.ImportdataButton.FontColor = '0.94,0.94,0.94';
            app.ImportdataButton.BackgroundColor = '0.07,0.17,0.24';
            app.ConversionButton.FontColor = '0.94,0.94,0.94';
            app.ConversionButton.BackgroundColor = '0.07,0.17,0.24';
            app.FastFourrierButton.FontColor = '0.94,0.94,0.94';
            app.FastFourrierButton.BackgroundColor = '0.07,0.17,0.24';
            app.OutlierremovalButton.FontColor = '0.94,0.94,0.94';
            app.OutlierremovalButton.BackgroundColor = '0.07,0.17,0.24';
            app.TrackAButton.FontColor = '0.94,0.94,0.94';
            app.TrackAButton.BackgroundColor = '0.07,0.17,0.24';
            app.TrackBButton.FontColor = '0.94,0.94,0.94';
            app.TrackBButton.BackgroundColor = '0.07,0.17,0.24';
            app.MVCNormalisationButton.FontColor = '0.94,0.94,0.94';
            app.MVCNormalisationButton.BackgroundColor = '0.07,0.17,0.24';
        end
        
        function [ch1FFT,ch2FFT,ch3FFT,ch4FFT,ch5FFT] = FFTFUNCTION(app,timedomainMatrix)

%           A FUNCTION THAT TAKES A MATRIX OF DATA IN THE TIME DOMAIN
%           MAKES THE FAST FOURIER TRANSFORM ON THAT MATRIX AND RETURN IT
%           BACK PER CHANNEL

            app.fs = 1000;
            L = length(app.nSeq);
            app.f = app.fs*(0:(L/2))/L;
            
            FFTMatrix = fft(timedomainMatrix);

%             disp(size(FFTMatrix));
            
            ch1FFT = FFTMatrix(:,1);
            ch2FFT = FFTMatrix(:,2);
            ch3FFT = FFTMatrix(:,3);
            ch4FFT = FFTMatrix(:,4);
            ch5FFT = FFTMatrix(:,5);

            ch1FFT = abs(ch1FFT/L);
            ch2FFT = abs(ch2FFT/L);
            ch3FFT = abs(ch3FFT/L);
            ch4FFT = abs(ch4FFT/L);
            ch5FFT = abs(ch5FFT/L);
     
            ch1FFT = ch1FFT(1:L/2+1);
            ch1FFT(2:end-1) = 2*ch1FFT(2:end-1);
            ch2FFT = ch2FFT(1:L/2+1);
            ch2FFT(2:end-1) = 2*ch2FFT(2:end-1);
            ch3FFT = ch3FFT(1:L/2+1);
            ch3FFT(2:end-1) = 2*ch3FFT(2:end-1);
            ch4FFT = ch4FFT(1:L/2+1);
            ch4FFT(2:end-1) = 2*ch4FFT(2:end-1);
            ch5FFT = ch5FFT(1:L/2+1);
            ch5FFT(2:end-1) = 2*ch5FFT(2:end-1);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)

% ON START OF THE APPLICATION WE LOAD OUR FILES WITH TXT EXTENSION
% THESE FILES CONTAIN THE SEMG DATA
% IN THIS FILE THE DATA IS ORDERED IN A TABLE OF 5 CHANNELS
% THIS DATA IS STORED IN A MATRIX FOR FURTHER OPERATIONS
% AT THE END OF THE FUNCTION THE BUTTONS FOR FURTHER CALCULATIONS ARE
% ENABLED (IN THIS CASE THE BUTTON FOR CALCULATION OF THE MV DATA)

[filename,path]=uigetfile("DATA/PP01/*.txt");
if isequal(filename,0)
    disp("NO FILES SELECTED")
else
    pathToFile = path + "/" + filename;
    tmpFilename = strrep(filename, '_',' ');
    app.legendFilename = strrep(tmpFilename, '.txt','');
    fileID = fopen(pathToFile);
    loadedData = readmatrix(pathToFile);
    app.UITable.Data = loadedData;
    fclose(fileID);
    figure(app.UIFigure);
    
% THE MATRIX HERE IS DIVIDED INTO MULTIPLE SUBMATRIXES OF SEPERATE CHANNEL
% DATA STORED IN EACH MATRIX
% FINALLY THIS DATA IS PLOTTED ON THE IMPORT DATA TAB OF THE APPLICATION

    app.rawChannelData = loadedData(:,3:end);
    app.nSeq = loadedData(:,1);
    app.YourdataLabel.Visible = 'on';
    
    channel1RawData = app.rawChannelData(:,1);
    channel2RawData = app.rawChannelData(:,2);
    channel3RawData = app.rawChannelData(:,3);
    channel4RawData = app.rawChannelData(:,4);
    channel5RawData = app.rawChannelData(:,5);
    
    plot(app.RawDataChannel1,app.nSeq,channel1RawData);
    legend(app.RawDataChannel1, sprintf('%s raw data', app.legendFilename));
    plot(app.RawDataChannel2,app.nSeq,channel2RawData);
    legend(app.RawDataChannel2, sprintf('%s raw data', app.legendFilename));
    plot(app.RawDataChannel3,app.nSeq,channel3RawData);
    legend(app.RawDataChannel3, sprintf('%s raw data', app.legendFilename));
    plot(app.RawDataChannel4,app.nSeq,channel4RawData);
    legend(app.RawDataChannel4, sprintf('%s raw data', app.legendFilename));
    plot(app.RawDataChannel5,app.nSeq,channel5RawData);
    legend(app.RawDataChannel5, sprintf('%s raw data', app.legendFilename));
    
    if (app.SettingsCounter == 1)
        app.SettingsUsed = app.SettingsUsed + ("{Import: " + pathToFile + "}");
        app.SettingsCounter = app.SettingsCounter +1;
    end
    
    app.ConversionButton.Enable = 'on';
    app.ConverttomVButton.Enable = 'on';
    app.ImageConversion.Enable = 'on';
    app.mVDataMatrix = ((app.rawChannelData ./ (2^16)) - (0.5)) * 3;
end

        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.DocumentationOutliers.Value;

        end

        % Button pushed function: ConverttomVButton
        function ConverttomVButtonPushed(app, event)
                
%           ON PRESSING THE CONVERTION TO MV BUTTON
%           A FORMULA WILL BE APPLIED TO THE RAW DATA MATRIX
%           THIS FORMULA MAKES A CALCULATION ON THE RAW DATA
%           AND CONVERTS IT TO MV DATA RANGING BETWEEN (-1.5,1.5)MV
%           AT THE END OF THE FUNCTION THE BUTTON FOR CALCULATION
%           THE FREQUENCY DOMAIN OF THIS DATA (FFT) IS ENABLED

            app.UITable_2.Data = app.mVDataMatrix;

            app.channel1MvData = app.mVDataMatrix(:,1);
            app.channel2MvData = app.mVDataMatrix(:,2);
            app.channel3MvData = app.mVDataMatrix(:,3);
            app.channel4MvData = app.mVDataMatrix(:,4);
            app.channel5MvData = app.mVDataMatrix(:,5);

            plot(app.MvDataChannel1,app.nSeq,app.channel1MvData);
            legend(app.MvDataChannel1, sprintf('%s mV data', app.legendFilename));
            plot(app.MvDataChannel2,app.nSeq,app.channel2MvData);
            legend(app.MvDataChannel2, sprintf('%s mV data', app.legendFilename));
            plot(app.MvDataChannel3,app.nSeq,app.channel3MvData);
            legend(app.MvDataChannel3, sprintf('%s mV data', app.legendFilename));
            plot(app.MvDataChannel4,app.nSeq,app.channel4MvData);
            legend(app.MvDataChannel4, sprintf('%s mV data', app.legendFilename));
            plot(app.MvDataChannel5,app.nSeq,app.channel5MvData);
            legend(app.MvDataChannel5, sprintf('%s mV data', app.legendFilename));

            app.mVDATALabel.Visible = 'on';
            
            if (app.SettingsCounter == 2)
                app.SettingsUsed = app.SettingsUsed + ",{MV}";
                app.SettingsCounter = app.SettingsCounter +1;
            end

            app.FastFourrierButton.Enable = 'on';
            app.FFTButton.Enable = 'on';
            app.ImageFastFourrier.Enable = 'on';

        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
            
%           FROM THE MV DATA A FAST FOURIER TRANSFORM WILL BE CALCULATED
%           AND AGAIN SEPERATED IN MULTIPLE CHANNELS TO FINALLY PLOT THE
%           DATA. FINALLY THE OUTLIER REMOVAL BUTTON WILL BE ENABLED FOR
%           THE USER IN THE GUI

            [ch1,ch2,ch3,ch4,ch5] = app.FFTFUNCTION(app.mVDataMatrix);

            app.Channel1FFTData = ch1;
            app.Channel2FFTData = ch2; 
            app.Channel3FFTData = ch3;
            app.Channel4FFTData = ch4;
            app.Channel5FFTData = ch5;
                                 
            plot(app.FFTDataChannel1,app.f,app.Channel1FFTData);
            legend(app.FFTDataChannel1, sprintf('%s fourrier data', app.legendFilename));
            plot(app.FFTDataChannel2,app.f,app.Channel2FFTData);
            legend(app.FFTDataChannel2, sprintf('%s fourrier data', app.legendFilename));
            plot(app.FFTDataChannel3,app.f,app.Channel3FFTData);
            legend(app.FFTDataChannel3, sprintf('%s fourrier data', app.legendFilename));
            plot(app.FFTDataChannel4,app.f,app.Channel4FFTData);
            legend(app.FFTDataChannel4, sprintf('%s fourrier data', app.legendFilename));
            plot(app.FFTDataChannel5,app.f,app.Channel5FFTData);
            legend(app.FFTDataChannel5, sprintf('%s fourrier data', app.legendFilename));

            if (app.SettingsCounter == 3)
                app.SettingsUsed = app.SettingsUsed + ",{FFT}";
                app.SettingsCounter = app.SettingsCounter +1;
            end
         
            app.OutlierremovalButton.Enable = 'on';
            app.OutlierDeviationOptionDropdown.Enable = 'on';
            app.REMOVEOUTLIERButton.Enable = 'on';
            app.ImageOutlier.Enable = 'on';

        end

        % Button pushed function: REMOVEOUTLIERButton
        function REMOVEOUTLIERButtonPushed(app, event)
            
%           AFTER FAST FOURIER IMPLEMENTATION TO VIEW THE DATA IN THE
%           FREQUENCY DOMAIN THE OUTLIER REMOVAL OPTION WILL BE ENABLED
%           THIS WILL TAKE THE MV DATA TO REMOVE THE OUTLIERS AND FINALLY
%           FILL IT BACK IN ACCORDING TO MULTIPLE ALGORITHMS THAT CAN BE
%           SELECTED AND PASSED IN AS PARAMETER ON THE GUI. THE OUTLIER
%           REMOVED DATA WILL BE STORED DIFFERENT MATRIXES PER CHANNEL AND
%           THE DATA FILL FINALLY BE PLOTTED AND COMPARED TO THE ORIGINAL
%           MV DATA.
%           PARALLEL TO THIS THE FREQUENCY DOMAIN OF THESE DATASETS WILL
%           ALSO BE CALCULATED AND PLOTTED SO THAT THE USER CAN SEE THE
%           RESPONSE IN THE FREQUENCY AND TIME DOMAIN FOR COMPARISSONS.
%           FINALLY THE PROCESSING TRACK A AND B BUTTONS WILL BE ENABLED
%           AFTER THIS PROCESS ON THE GUI.
            
            
            % CLEAR ALLE PLOTS
            cla(app.OutlierDataChannel1);
            cla(app.OutlierDataChannel2);
            cla(app.OutlierDataChannel3); 
            cla(app.OutlierDataChannel4); 
            cla(app.OutlierDataChannel5); 

            if(app.removeOutlierOption == "grubbs" && (app.thresholdFactor ~= 1))
                uialert(app.UIFigure,'When choosing grubbs option thresholdFactor must be between 0 and 1','An error occured');
            elseif(app.removeOutlierOption == "gesd" && (app.thresholdFactor ~= 1))
                uialert(app.UIFigure,'When choosing gesd option thresholdFactor must be between 0 and 1','An error occured');
            else
                app.Outlier1Channel1 = filloutliers(app.channel1MvData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                app.Outlier1Channel2 = filloutliers(app.channel2MvData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                app.Outlier1Channel3 = filloutliers(app.channel3MvData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                app.Outlier1Channel4 = filloutliers(app.channel4MvData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);
                app.Outlier1Channel5 = filloutliers(app.channel5MvData,app.fillOutlierOption,app.removeOutlierOption,"ThresholdFactor",app.thresholdFactor);

%               TIME DOMAIN    
                hold(app.OutlierDataChannel1,'on');
                plot(app.OutlierDataChannel1,app.nSeq,app.channel1MvData);
                plot(app.OutlierDataChannel1,app.nSeq,app.Outlier1Channel1,'r');
                legend(app.OutlierDataChannel1, sprintf('%s mV data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel1,'off');
                hold(app.OutlierDataChannel2,'on');
                plot(app.OutlierDataChannel2,app.nSeq,app.channel2MvData);
                plot(app.OutlierDataChannel2,app.nSeq,app.Outlier1Channel2,'r');
                legend(app.OutlierDataChannel2, sprintf('%s mV data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel2,'off');
                hold(app.OutlierDataChannel3,'on');
                plot(app.OutlierDataChannel3,app.nSeq,app.channel3MvData);
                plot(app.OutlierDataChannel3,app.nSeq,app.Outlier1Channel3,'r');
                legend(app.OutlierDataChannel3, sprintf('%s mV data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel3,'off');
                hold(app.OutlierDataChannel4,'on');
                plot(app.OutlierDataChannel4,app.nSeq,app.channel4MvData);
                plot(app.OutlierDataChannel4,app.nSeq,app.Outlier1Channel4,'r');
                legend(app.OutlierDataChannel4, sprintf('%s mV data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel4,'off');
                hold(app.OutlierDataChannel5,'on');
                plot(app.OutlierDataChannel5,app.nSeq,app.channel5MvData);
                plot(app.OutlierDataChannel5,app.nSeq,app.Outlier1Channel5,'r');
                legend(app.OutlierDataChannel5, sprintf('%s mV data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel5,'off');

%               FREQUENCY DOMAIN
                fullOutlierMatrix = [app.Outlier1Channel1,app.Outlier1Channel2,app.Outlier1Channel3,app.Outlier1Channel4,app.Outlier1Channel5];
                [ch1Mv,ch2Mv,ch3Mv,ch4Mv,ch5Mv] = app.FFTFUNCTION(app.mVDataMatrix);
                [ch1Out,ch2Out,ch3Out,ch4Out,ch5Out] = app.FFTFUNCTION(fullOutlierMatrix);

                hold(app.OutlierDataChannel1_FD,'on');
                plot(app.OutlierDataChannel1_FD,app.f,ch1Mv);
                plot(app.OutlierDataChannel1_FD,app.f,ch1Out,'r');
                legend(app.OutlierDataChannel1_FD, sprintf('%s fourrier data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel1_FD,'off');
                hold(app.OutlierDataChannel2_FD,'on');
                plot(app.OutlierDataChannel2_FD,app.f,ch2Mv);
                plot(app.OutlierDataChannel2_FD,app.f,ch2Out,'r');
                legend(app.OutlierDataChannel2_FD, sprintf('%s fourrier data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel2_FD,'off');
                hold(app.OutlierDataChannel3_FD,'on');
                plot(app.OutlierDataChannel3_FD,app.f,ch3Mv);
                plot(app.OutlierDataChannel3_FD,app.f,ch3Out,'r');
                legend(app.OutlierDataChannel3_FD, sprintf('%s fourrier data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel3_FD,'off');
                hold(app.OutlierDataChannel4_FD,'on');
                plot(app.OutlierDataChannel4_FD,app.f,ch4Mv);
                plot(app.OutlierDataChannel4_FD,app.f,ch4Out,'r');
                legend(app.OutlierDataChannel4_FD, sprintf('%s fourrier data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel4_FD,'off');
                hold(app.OutlierDataChannel5_FD,'on');
                plot(app.OutlierDataChannel5_FD,app.f,ch5Mv);
                plot(app.OutlierDataChannel5_FD,app.f,ch5Out,'r');
                legend(app.OutlierDataChannel5_FD, sprintf('%s fourrier data', app.legendFilename), 'Outliers removed');
                hold(app.OutlierDataChannel5_FD,'off');
             
                if (app.SettingsCounter == 4)
                    app.SettingsUsed = app.SettingsUsed + ("{Outliers: " + "{" + app.thresholdFactor + "} {" + app.RemoveOutlierOptionDropdown.Value + "} {" + app.FillOutlierOptionDropdown.Value + "}}");
                    app.SettingsCounter = app.SettingsCounter +1;
                end
    
%               BUTTON ENABLING  
                app.TrackAButton.Enable = 'on';
                app.TrackBButton.Enable = 'on';
                app.ImageA.Enable = 'on';
                app.ImageB.Enable = 'on';
                app.FilterOrderDropdown.Enable = 'on';
                app.FilterTypeDropdown.Enable = 'on';
                app.ApplyfilterButton.Enable = 'on';
                app.ApplyButton.Enable = 'on';
                app.TrackA_FC1.Enable = 'on';
            end

        end

        % Value changed function: OutlierDeviationOptionDropdown
        function OutlierDeviationOptionDropdownValueChanged(app, event)
            value = app.OutlierDeviationOptionDropdown.Value;
            app.thresholdFactor = str2num(value);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
%           PROCESSING TRACK B WILL CALCULATE THE RMS DATA OF OUR OUTLIER
%           REMOVED DATA. THE WINDOW SIZE CAN BE PASSED IN AS A PARAMETER.
%           FINALLY THE RESULTS WILL BE PLOTED AND COMPARED TO THE OUTLIER
%           REMOVED MV DATA. THE FREQUENCY DOMAIN WILL ALSO BE PLOTTED.
            
            % CLEAR ALLE PLOTS
            cla(app.TrackBchannel1_2);
            cla(app.TrackBchannel2_2);
            cla(app.TrackBchannel3_2);
            cla(app.TrackBchannel4_2);
            cla(app.TrackBchannel5_2);
            cla(app.TrackBchannel1_FD);
            cla(app.TrackBchannel2_FD);
            cla(app.TrackBchannel3_FD);
            cla(app.TrackBchannel4_FD);
            cla(app.TrackBchannel5_FD);
            
            Window = app.windowSizeTrackB;  
            
            rmsChannel1 = app.Outlier1Channel1;
            rmsChannel2 = app.Outlier1Channel2;
            rmsChannel3 = app.Outlier1Channel3;
            rmsChannel4 = app.Outlier1Channel4;
            rmsChannel5 = app.Outlier1Channel5;
                                   
%           RMS CALCULATION
            app.rmsC1 = sqrt(movmean(rmsChannel1.^2, Window));                         
            app.rmsC2 = sqrt(movmean(rmsChannel2.^2, Window)); 
            app.rmsC3 = sqrt(movmean(rmsChannel3.^2, Window)); 
            app.rmsC4 = sqrt(movmean(rmsChannel4.^2, Window)); 
            app.rmsC5 = sqrt(movmean(rmsChannel5.^2, Window)); 

%           TIME DOMAIN
            hold(app.TrackBchannel1_2,"on");
            plot(app.TrackBchannel1_2,app.nSeq, abs(rmsChannel1));
            plot(app.TrackBchannel1_2,app.nSeq, app.rmsC1, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel1_2, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackBchannel1_2,"off");
            
            hold(app.TrackBchannel2_2,"on");
            plot(app.TrackBchannel2_2,app.nSeq, abs(rmsChannel2));
            plot(app.TrackBchannel2_2,app.nSeq, app.rmsC2, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel2_2, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackBchannel2_2,"off");
            
            hold(app.TrackBchannel3_2,"on");
            plot(app.TrackBchannel3_2,app.nSeq, abs(rmsChannel3));
            plot(app.TrackBchannel3_2,app.nSeq, app.rmsC3, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel3_2, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackBchannel3_2,"off");
            
            hold(app.TrackBchannel4_2,"on");
            plot(app.TrackBchannel4_2,app.nSeq, abs(rmsChannel4));
            plot(app.TrackBchannel4_2,app.nSeq, app.rmsC4, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel4_2, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackBchannel4_2,"off");
            
            hold(app.TrackBchannel5_2,"on");
            plot(app.TrackBchannel5_2,app.nSeq, abs(rmsChannel5));
            plot(app.TrackBchannel5_2,app.nSeq, app.rmsC5, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel5_2, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackBchannel5_2,"off");

%            FREQUENCY DOMAIN
             fullRMSChannelMatrix = [rmsChannel1,rmsChannel2,rmsChannel3,rmsChannel4,rmsChannel5];
             fullRMSC = [app.rmsC1,app.rmsC2,app.rmsC3,app.rmsC4,app.rmsC5];

             [ch1RMS,ch2RMS,ch3RMS,ch4RMS,ch5RMS] = app.FFTFUNCTION(fullRMSChannelMatrix);
             [ch1RMSC,ch2RMSC,ch3RMSC,ch4RMSC,ch5RMSC] = app.FFTFUNCTION(fullRMSC);

            hold(app.TrackBchannel1_FD,"on");
            plot(app.TrackBchannel1_FD,app.f, ch1RMS);
            plot(app.TrackBchannel1_FD,app.f, ch1RMSC, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel1_FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackBchannel1_FD,"off");
            
            hold(app.TrackBchannel2_FD,"on");
            plot(app.TrackBchannel2_FD,app.f, ch2RMS);
            plot(app.TrackBchannel2_FD,app.f, ch2RMSC, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel2_FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackBchannel2_FD,"off");
            
            hold(app.TrackBchannel3_FD,"on");
            plot(app.TrackBchannel3_FD,app.f, ch3RMS);
            plot(app.TrackBchannel3_FD,app.f, ch3RMSC, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel3_FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackBchannel3_FD,"off");
            
            hold(app.TrackBchannel4_FD,"on");
            plot(app.TrackBchannel4_FD,app.f, ch4RMS);
            plot(app.TrackBchannel4_FD,app.f, ch4RMSC, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel4_FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackBchannel4_FD,"off");
            
            hold(app.TrackBchannel5_FD,"on");
            plot(app.TrackBchannel5_FD,app.f, ch5RMS);
            plot(app.TrackBchannel5_FD,app.f, ch5RMSC, '-r', 'LineWidth',1.5);
            legend(app.TrackBchannel5_FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackBchannel5_FD,"off");

            
            if (app.SettingsCounter == 6)
                app.SettingsUsed = app.SettingsUsed + (",{Track B: {" + app.windowSizeTrackB +"}}");
                app.SettingsCounter = app.SettingsCounter +1;
            end
            
            app.MVCNormalisationButton.Enable = "on";
            app.MVCImage.Enable = 'on';
            
        end

        % Button pushed function: ApplyfilterButton
        function ApplyfilterButtonPushed(app, event)

%           PROCESSING TRACK A WILL FILTER THE DATA OF THE OUTLIER REMOVED
%           DATA. MULTIPLE FILTERING OPTIONS CAN BE SELECTED AND PASSED IN
%           AS A PARAMETER. THE FILTER1 MATLAB ADD ON IS USED TO MAKE THE
%           NECESSARY CALCULATIONS. NEXT THE ABSOLUTE VALUE OF THE DATA
%           WILL BE TAKEN FOR FULL WAVE RECTIFICATION. THE FINAL DATA WILL
%           BE PLOTTED PER CHANNEL. AGAIN THE RESPONSE IN THE FREQUENCY
%           DOMAIN IS ALSO CALCULATED USING THE FFT FUNCTION WE CREATED,
%           AND PLOTTED.

            % CLEAR ALLE PLOTS
            cla(app.TrackAchannel1);
            cla(app.TrackAchannel2);
            cla(app.TrackAchannel3); 
            cla(app.TrackAchannel4); 
            cla(app.TrackAchannel5); 
            cla(app.TrackAchannel1FD);
            cla(app.TrackAchannel2FD);
            cla(app.TrackAchannel3FD); 
            cla(app.TrackAchannel4FD); 
            cla(app.TrackAchannel5FD); 

            % NYQUIST FREQ
            nyF = app.fs/2;

            % FILTER THE DATA in mV          
            if(isequal(num2str(app.FilterType),'hp') || isequal(num2str(app.FilterType),'lp'))
                    app.FilterDataCh1 =  filter1(num2str(app.FilterType),app.Outlier1Channel1,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh2 = filter1(num2str(app.FilterType),app.Outlier1Channel2,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh3 = filter1(num2str(app.FilterType),app.Outlier1Channel3,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh4 = filter1(num2str(app.FilterType),app.Outlier1Channel4,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh5 = filter1(num2str(app.FilterType),app.Outlier1Channel5,'x',app.nSeq,'fc', app.TrackA_FC1.Value/nyF,'order',uint16(app.FilterOrder));                   
            elseif(isequal(num2str(app.FilterType),'bp') || isequal(num2str(app.FilterType),'bs'))
                    app.FilterDataCh1 =  filter1(num2str(app.FilterType),app.Outlier1Channel1,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order', uint16(app.FilterOrder));
                    app.FilterDataCh2 = filter1(num2str(app.FilterType),app.Outlier1Channel2,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh3 = filter1(num2str(app.FilterType),app.Outlier1Channel3,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh4 = filter1(num2str(app.FilterType),app.Outlier1Channel4,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
                    app.FilterDataCh5 = filter1(num2str(app.FilterType),app.Outlier1Channel5,'x',app.nSeq,'fc', [app.TrackA_FC1.Value,app.TrackA_FC2.Value]/nyF,'order',uint16(app.FilterOrder));
            end

            beforeRectCH1 = app.FilterDataCh1;
            beforeRectCH2 = app.FilterDataCh2;
            beforeRectCH3 = app.FilterDataCh3;
            beforeRectCH4 = app.FilterDataCh4;
            beforeRectCH5 = app.FilterDataCh5;

%           FULL WAVE RECTIFICATION
            app.FilterDataCh1 = abs(app.FilterDataCh1);
            app.FilterDataCh2 = abs(app.FilterDataCh2);
            app.FilterDataCh3 = abs(app.FilterDataCh3);
            app.FilterDataCh4 = abs(app.FilterDataCh4);
            app.FilterDataCh5 = abs(app.FilterDataCh5);

%           TIME DOMAIN  
            hold(app.TrackAchannel1,'on');
            plot(app.TrackAchannel1,app.nSeq,abs(app.Outlier1Channel1));
            plot(app.TrackAchannel1,app.nSeq,app.FilterDataCh1);
            legend(app.TrackAchannel1, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackAchannel1,'off');

            hold(app.TrackAchannel2,'on');
            plot(app.TrackAchannel2,app.nSeq,abs(app.Outlier1Channel2));
            plot(app.TrackAchannel2,app.nSeq,app.FilterDataCh2);
            legend(app.TrackAchannel2, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackAchannel2,'off');
           
            hold(app.TrackAchannel3,'on');
            plot(app.TrackAchannel3,app.nSeq,abs(app.Outlier1Channel3));
            plot(app.TrackAchannel3,app.nSeq,app.FilterDataCh3);
            legend(app.TrackAchannel3, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackAchannel3,'off');

            hold(app.TrackAchannel4,'on');
            plot(app.TrackAchannel4,app.nSeq,abs(app.Outlier1Channel4));
            plot(app.TrackAchannel4,app.nSeq,app.FilterDataCh4);
            legend(app.TrackAchannel4, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackAchannel4,'off');

            hold(app.TrackAchannel5,'on');
            plot(app.TrackAchannel5,app.nSeq,abs(app.Outlier1Channel5));
            plot(app.TrackAchannel5,app.nSeq,app.FilterDataCh5);
            legend(app.TrackAchannel5, sprintf('%s mV data', app.legendFilename), 'With options');
            hold(app.TrackAchannel5,'off');

%            FREQUENCY DOMAIN
             fullOutlierMatrix = [app.Outlier1Channel1,app.Outlier1Channel2,app.Outlier1Channel3,app.Outlier1Channel4,app.Outlier1Channel5];
             fullFilterMatrix = [beforeRectCH1,beforeRectCH2,beforeRectCH3,beforeRectCH4,beforeRectCH5];

             [ch1O,ch2O,ch3O,ch4O,ch5O] = app.FFTFUNCTION(fullOutlierMatrix);
             [ch1F,ch2F,ch3F,ch4F,ch5F] = app.FFTFUNCTION(fullFilterMatrix);

            hold(app.TrackAchannel1FD,'on');
            plot(app.TrackAchannel1FD,app.f/2,ch1O);
            plot(app.TrackAchannel1FD,app.f/2,ch1F);
            legend(app.TrackAchannel1FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackAchannel1FD,'off');

            hold(app.TrackAchannel2FD,'on');
            plot(app.TrackAchannel2FD,app.f/2,ch2O);
            plot(app.TrackAchannel2FD,app.f/2,ch2F);
            legend(app.TrackAchannel2FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackAchannel2FD,'off');
           
            hold(app.TrackAchannel3FD,'on');
            plot(app.TrackAchannel3FD,app.f/2,ch3O);
            plot(app.TrackAchannel3FD,app.f/2,ch3F);
            legend(app.TrackAchannel3FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackAchannel3FD,'off');

            hold(app.TrackAchannel4FD,'on');
            plot(app.TrackAchannel4FD,app.f/2,ch4O);
            plot(app.TrackAchannel4FD,app.f/2,ch4F);
            legend(app.TrackAchannel4FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackAchannel4FD,'off');

            hold(app.TrackAchannel5FD,'on');
            plot(app.TrackAchannel5FD,app.f/2,ch5O);
            plot(app.TrackAchannel5FD,app.f/2,ch5F);
            legend(app.TrackAchannel5FD, sprintf('%s fourrier data', app.legendFilename), 'With options');
            hold(app.TrackAchannel5FD,'off');


            if (app.SettingsCounter == 5)
                app.SettingsUsed = app.SettingsUsed + (",{Track A: {" + app.FilterType + "} { " + app.FilterOrder + "} {" + app.TrackA_FC1.Value + "} {" + app.TrackA_FC2.Value +"}");
                app.SettingsCounter = app.SettingsCounter +1;
            end
                           
        end

        % Value changed function: FilterTypeDropdown
        function FilterTypeDropdownValueChanged(app, event)
%           DROPDOWN FILTER OPTION SELECT  
            
            value = app.FilterTypeDropdown.Value;
            if value == "High-pass"
                app.TrackA_FC2.Enable = 'off';
                app.Cuttofffrequency2Label.Visible = "off";
                app.TrackA_FC2.Visible = "off";
                app.FilterType = 'hp';
            elseif value == "Low-pass"
                app.TrackA_FC2.Enable = 'off';
                app.Cuttofffrequency2Label.Visible = "off";
                app.TrackA_FC2.Visible = "off";
                app.FilterType = 'lp';
            elseif value == "Band-pass"
                app.TrackA_FC2.Enable = 'on';
                app.Cuttofffrequency2Label.Visible = "on";
                app.TrackA_FC2.Visible = "on";
                app.FilterType = 'bp';
            elseif value == "Bandstop"
                app.TrackA_FC2.Enable = 'on';
                app.Cuttofffrequency2Label.Visible = "on";
                app.TrackA_FC2.Visible = "on";
                app.FilterType = 'bs';
            end

        end

        % Value changed function: FilterOrderDropdown
        function FilterOrderDropdownValueChanged(app, event)
%           DROPDOWN TO SELECT THE FILTER ORDER CURRENTLY BETWEEN 1-5  
            
            value = app.FilterOrderDropdown.Value;
            if value == "1"
                app.FilterOrder = 1;
            elseif value == "2"
                app.FilterOrder = 2;
            elseif value == "3"
                app.FilterOrder = 3;
            elseif value == "4"
                app.FilterOrder = 4;
            elseif value == "5"
                app.FilterOrder = 5;
            end
            
        end

        % Button pushed function: ExportTrackA
        function ExportTrackAPushed(app, event)
            
            % EXPORT THE DATA OF PROCESSING TRACK A IN AN EXCEL SHEET
            % A DIRECTORY WILL BE CREATED IF IT DOESNT EXIST YET. IN THIS
            % DIRECTORY THE EXCEL SHEETS WILL BE STORED.

            settingsHeader = ["===" "start" "settings" "header" "==="; app.SettingsUsed "" "" "" ""; "===" "end" "settings" "header" "==="];
            labels = ["CH1" "CH2" "CH3" "CH4" "CH5"];
            matrix = [app.FilterDataCh1 app.FilterDataCh2 app.FilterDataCh3 app.FilterDataCh4 app.FilterDataCh5];
            export = [settingsHeader; labels; matrix];
            file = 'C:\sEMG_Analysis_Toolbox_Export\exportTrackA.xlsx';
            if not(isfolder('C:\sEMG_Analysis_Toolbox_Export\'))
                mkdir('C:\sEMG_Analysis_Toolbox_Export\');
            end
            writematrix(export,file);  
            type exportTrackA.xlsx
            
        end

        % Value changed function: FillOutlierOptionDropdown
        function FillOutlierOptionDropdownValueChanged(app, event)
            value = app.FillOutlierOptionDropdown.Value;
            app.fillOutlierOption = value;
        end

        % Value changed function: RemoveOutlierOptionDropdown
        function RemoveOutlierOptionDropdownValueChanged(app, event)
            value = app.RemoveOutlierOptionDropdown.Value;
            app.removeOutlierOption = value;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%           UI COLOR CONFIGURATIONS  
            
            if (app.ImportdataButton.Value == true)
                ResetButtonColor(app);
                app.ImportdataButton.FontColor = '0.86,0.65,0.30';
                app.ImportdataButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.RAWDATATab;
            end
            if (app.ConversionButton.Value == true)
                ResetButtonColor(app);
                app.ConversionButton.FontColor = '0.86,0.65,0.30';
                app.ConversionButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.mVDATATab;
            end
            if (app.FastFourrierButton.Value == true)
                ResetButtonColor(app);
                app.FastFourrierButton.FontColor = '0.86,0.65,0.30';
                app.FastFourrierButton.BackgroundColor = '0.13,0.33,0.46';
                app.TabGroup.SelectedTab = app.FFTANALYSISTab;
            end
            if (app.OutlierremovalButton.Value == true)
                ResetButtonColor(app);
                app.OutlierremovalButton.FontColor = '0.86,0.65,0.30';
                app.OutlierremovalButton.BackgroundColor = '0.13,0.33,0.46';
                
                app.TabGroup.SelectedTab = app.REMOVEOUTLIERTab;
            end
            
            if (app.TrackAButton.Value == true)
                ResetButtonColor(app);
                app.TrackAButton.FontColor = '0.86,0.65,0.30';
                app.TrackAButton.BackgroundColor = '0.13,0.33,0.46';
                
                app.TabGroup.SelectedTab = app.PROCESSINGTRACKATab;
            end
            if (app.TrackBButton.Value == true)
                ResetButtonColor(app);
                app.TrackBButton.FontColor = '0.86,0.65,0.30';
                app.TrackBButton.BackgroundColor = '0.13,0.33,0.46';
                
                app.TabGroup.SelectedTab = app.PROCESSINGTRACKBTab;
            end
            
            if (app.MVCNormalisationButton.Value == true)
                ResetButtonColor(app);
                app.MVCNormalisationButton.FontColor = '0.86,0.65,0.30';
                app.MVCNORMALISATIONButton.BackgroundColor = '0.13,0.33,0.46';
                
                app.TabGroup.SelectedTab = app.MVCNORMALISATIONTab;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%           EXIT THE APPLICATION  
            
            YN = uiconfirm(app.UIFigure,'Do you want to close the app?', 'Confirm close', 'Icon','warning', 'Options',{'Yes', 'No'});
            if strcmpi(YN,'Yes')
                delete(app)
            end
        end

        % Image clicked function: ExitImage
        function ExitImageClicked(app, event)
            UIFigureCloseRequest(app);
        end

        % Value changed function: TrackA_FC2
        function TrackA_FC2ValueChanged(app, event)
            value = app.TrackA_FC2.Value;
            
        end

        % Value changed function: TrackA_FC1
        function TrackA_FC1ValueChanged(app, event)
            value = app.TrackA_FC1.Value;
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % EXPORT DATA IN EXCEL SHEET
            % A DIRECTORY WILL BE CREATED IF IT DOESNT EXIST YET. IN THIS
            % DIRECTORY THE EXCEL SHEETS WILL BE STORED.

            settingsHeader = ["===" "start" "settings" "header" "==="; app.SettingsUsed "" "" "" ""; "===" "end" "settings" "header" "==="];
            labels = ["CH1" "CH2" "CH3" "CH4" "CH5"];
            matrix = [app.MVC_normalization1 app.MVC_normalization2 app.MVC_normalization3 app.MVC_normalization4 app.MVC_normalization5];
            export = [settingsHeader; labels; matrix];
            file = 'C:\sEMG_Analysis_Toolbox_Export\exportMVC.xlsx';
            if not(isfolder('C:\sEMG_Analysis_Toolbox_Export\'))
                mkdir('C:\sEMG_Analysis_Toolbox_Export\');
            end
            writematrix(export,file);  
            type exportMVC.xlsx
            
        end

        % Button pushed function: MVCNORMALISATIONButton
        function MVCNORMALISATIONButtonPushed(app, event)
        
%          FINAL STEP OF THE SEMG ANALYSIS TOOLBOX IS MVC NORMALISATION
%          CALCULATION. AFTER THIS BUTTON IS PRESSED THE USER WILL HAVE TO
%          SELCT AND LOAD IN MULTIPLE MVC FILES. THE MAX VALUES WILL BE
%          FIND AND FINALLY THE MVC CALCULATION WILL TAKE PLACE. THE
%          RESULTS WILL BE PLOTTED.

           Data1 = max(max(app.MVCFile1));
           Data2 = max(max(app.MVCFile2));
           Data3 = max(max(app.MVCFile3));
           Data4 = max(max(app.MVCFile4));
           Data5 = max(max(app.MVCFile5));

%          TO MV VALUES
           Data1 = ((Data1 / (2^16)) - (0.5)) * 3;
           Data2 = ((Data2 / (2^16)) - (0.5)) * 3;
           Data3 = ((Data3 / (2^16)) - (0.5)) * 3;
           Data4 = ((Data4 / (2^16)) - (0.5)) * 3;
           Data5 = ((Data5 / (2^16)) - (0.5)) * 3;

           MVC = [Data1 Data2 Data3 Data4 Data5];

%            disp(MVC);

           if(isequal(app.MVCdropdownOption,'Track A Filter'))
               app.MVC_normalization1 = (app.FilterDataCh1./MVC(1,1));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization2 = (app.FilterDataCh2./MVC(1,2));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization3 = (app.FilterDataCh3./MVC(1,3));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization4 = (app.FilterDataCh4./MVC(1,4));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization5 = (app.FilterDataCh5./MVC(1,5));%.*1000; %de x1000 is om de waarde tussen 0 en 1
           elseif(isequal(app.MVCdropdownOption,'Track B RMS'))
               app.MVC_normalization1 = (app.rmsC1./MVC(1,1));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization2 = (app.rmsC2./MVC(1,2));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization3 = (app.rmsC3./MVC(1,3));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization4 = (app.rmsC4./MVC(1,4));%.*1000; %de x1000 is om de waarde tussen 0 en 1
               app.MVC_normalization5 = (app.rmsC5./MVC(1,5));%.*1000; %de x1000 is om de waarde tussen 0 en 1
           end
                    
           matrix = [app.MVC_normalization1 app.MVC_normalization2 app.MVC_normalization3 app.MVC_normalization4 app.MVC_normalization5];
           app.UITableMVC.Data = matrix;
           app.YourdataMVC.Visible = "on";
          
           plot(app.MVCnormalisationChannel1, app.nSeq, app.MVC_normalization1);
           legend(app.MVCnormalisationChannel1, sprintf('%s normalised', app.legendFilename));
           plot(app.MVCnormalisationChannel2, app.nSeq, app.MVC_normalization2);
           legend(app.MVCnormalisationChannel2, sprintf('%s normalised', app.legendFilename));
           plot(app.MVCnormalisationChannel3, app.nSeq, app.MVC_normalization3);
           legend(app.MVCnormalisationChannel3, sprintf('%s normalised', app.legendFilename));
           plot(app.MVCnormalisationChannel4, app.nSeq, app.MVC_normalization4);
           legend(app.MVCnormalisationChannel4, sprintf('%s normalised', app.legendFilename));
           plot(app.MVCnormalisationChannel5, app.nSeq, app.MVC_normalization5);      
           legend(app.MVCnormalisationChannel5, sprintf('%s normalised', app.legendFilename));
         
            if (app.SettingsCounter == 7)
                app.SettingsUsed = app.SettingsUsed + (",{MVC Normalisation}}");
                app.SettingsCounter = app.SettingsCounter + 1;
            end
        end

        % Value changed function: WindowSizeTextbox
        function WindowSizeTextboxValueChanged(app, event)
            value = app.WindowSizeTextbox.Value;
%             app.ApplyButton.Enable = 'off';
%             if (value ~= 0)
%                 app.ApplyButton.Enable = 'on';
            app.windowSizeTrackB = value;
%                 
%             end
        end

        % Button pushed function: ExportTrackB
        function ExportTrackBButtonPushed(app, event)
             % EXPORT PROCESSING TRACK B DATA IN EXCEL SHEET.
             % A DIRECTORY WILL BE CREATED IF IT DOESNT EXIST YET. IN THIS
             % DIRECTORY THE EXCEL SHEETS WILL BE STORED.

            settingsHeader = ["===" "start" "settings" "header" "==="; app.SettingsUsed "" "" "" ""; "===" "end" "settings" "header" "==="];
            labels = ["CH1" "CH2" "CH3" "CH4" "CH5"];
            matrix = [app.rmsC1 app.rmsC2 app.rmsC3 app.rmsC4 app.rmsC5];
            export = [settingsHeader; labels; matrix];
            file = 'C:\sEMG_Analysis_Toolbox_Export\exportTrackB.xlsx';
            if not(isfolder('C:\sEMG_Analysis_Toolbox_Export\'))
                mkdir('C:\sEMG_Analysis_Toolbox_Export\');
            end
            writematrix(export,file);  
            type exportTrackB.xlsx
            
        end

        % Value changed function: DropDownMVC
        function DropDownMVCValueChanged(app, event)
            value = app.DropDownMVC.Value;
            app.MVCdropdownOption = value;
        end

        % Button pushed function: SELECTMVCFILESButton
        function SELECTMVCFILESButtonPushed(app, event)
%           THIS FUNCTION IS FOR THE MVC FILES SELECTION.
%           THE MATRIXES OF THESE MVC FILES BE CREATED SO THESE CAN BE USED
%           IN THE CALCULATION OF THE MAXIMUM VOLUNTARY CONTRACTION
%           CALCULATION. CURRENTLY THIS CODE OPERATES ON THE SELECTION OF 5
%           MVC FILES.
            
            [filename,path]=uigetfile("DATA/PP01/*.txt",'Select the INPUT DATA FILE(s)','MultiSelect','on');
            if isequal(filename,0)
                disp("NO FILES SELECTED")
            else
                pathToFile1 = path + "/" + filename(1);            
                fileID1 = fopen(pathToFile1);
                loadedData1 = readmatrix(pathToFile1);
                fclose(fileID1); 
    
                pathToFile2 = path + "/" + filename(2);            
                fileID2 = fopen(pathToFile2);
                loadedData2 = readmatrix(pathToFile2);
                fclose(fileID2); 
    
                pathToFile3 = path + "/" + filename(3);            
                fileID3 = fopen(pathToFile3);
                loadedData3 = readmatrix(pathToFile3);
                fclose(fileID3); 
    
                pathToFile4 = path + "/" + filename(4);            
                fileID4 = fopen(pathToFile4);
                loadedData4 = readmatrix(pathToFile4);
                fclose(fileID4); 
    
                pathToFile5 = path + "/" + filename(5);            
                fileID5 = fopen(pathToFile5);
                loadedData5 = readmatrix(pathToFile5);
                fclose(fileID5); 
    
                %STAP 2
                app.MVCFile1 = loadedData1(:,3:end);
                nSeqMVC1 = loadedData1(:,1);
    
                app.MVCFile2 = loadedData2(:,3:end);
                nSeqMVC2 = loadedData2(:,1);
    
                app.MVCFile3 = loadedData3(:,3:end);
                nSeqMVC3 = loadedData3(:,1);
    
                app.MVCFile4 = loadedData4(:,3:end);
                nSeqMVC4 = loadedData4(:,1);
    
                app.MVCFile5 = loadedData5(:,3:end);
                nSeqMVC5 = loadedData5(:,1);

                app.MVCNORMALISATIONButton.Enable = 'on';
                app.DropDownMVC.Enable = 'on';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RAWDATATab = uitab(app.TabGroup);
            app.RAWDATATab.Title = 'RAW DATA';
            app.TabGroup2 = uitabgroup(app.RAWDATATab);
            app.CH1Tab = uitab(app.TabGroup2);
            app.CH1Tab.Title = 'CH1';
            app.RawDataChannel1 = uiaxes(app.CH1Tab);
            title(app.RawDataChannel1, 'Data - Channel 1')
            xlabel(app.RawDataChannel1, 'nSeq')
            ylabel(app.RawDataChannel1, 'Sensor value')
            zlabel(app.RawDataChannel1, 'Z')
            app.CH2Tab = uitab(app.TabGroup2);
            app.CH2Tab.Title = 'CH2';
            app.RawDataChannel2 = uiaxes(app.CH2Tab);
            title(app.RawDataChannel2, 'Data - Channel 2')
            xlabel(app.RawDataChannel2, 'nSeq')
            ylabel(app.RawDataChannel2, 'Sensor value')
            zlabel(app.RawDataChannel2, 'Z')
            app.CH3Tab = uitab(app.TabGroup2);
            app.CH3Tab.Title = 'CH3';
            app.RawDataChannel3 = uiaxes(app.CH3Tab);
            title(app.RawDataChannel3, 'Data - Channel 3')
            xlabel(app.RawDataChannel3, 'nSeq')
            ylabel(app.RawDataChannel3, 'Sensor value')
            zlabel(app.RawDataChannel3, 'Z')
            app.CH4Tab = uitab(app.TabGroup2);
            app.CH4Tab.Title = 'CH4';
            app.RawDataChannel4 = uiaxes(app.CH4Tab);
            title(app.RawDataChannel4, 'Data - Channel 4')
            xlabel(app.RawDataChannel4, 'nSeq')
            ylabel(app.RawDataChannel4, 'Sensor value')
            zlabel(app.RawDataChannel4, 'Z')
            app.CH5Tab = uitab(app.TabGroup2);
            app.CH5Tab.Title = 'CH5';
            app.RawDataChannel5 = uiaxes(app.CH5Tab);
            title(app.RawDataChannel5, 'Data - Channel 5')
            xlabel(app.RawDataChannel5, 'nSeq')
            ylabel(app.RawDataChannel5, 'Sensor value')
            zlabel(app.RawDataChannel5, 'Z')
            app.YourdataLabel = uilabel(app.RAWDATATab);
            app.YourdataLabel.Visible = 'off';
            app.YourdataLabel.Text = 'Your data';
            app.UITable = uitable(app.RAWDATATab);
            app.UITable.ColumnName = {'nSeq'; 'DI'; 'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable.RowName = {};
            app.SelectfileButton = uibutton(app.RAWDATATab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.mVDATATab = uitab(app.TabGroup);
            app.mVDATATab.Title = 'mV DATA';
            app.TabGroup2_2 = uitabgroup(app.mVDATATab);
            app.CH1Tab_2 = uitab(app.TabGroup2_2);
            app.CH1Tab_2.Title = 'CH1';
            app.MvDataChannel1 = uiaxes(app.CH1Tab_2);
            title(app.MvDataChannel1, 'Data - Channel 1')
            xlabel(app.MvDataChannel1, 'nSeq')
            ylabel(app.MvDataChannel1, 'mV value')
            zlabel(app.MvDataChannel1, 'Z')
            app.CH2Tab_2 = uitab(app.TabGroup2_2);
            app.CH2Tab_2.Title = 'CH2';
            app.MvDataChannel2 = uiaxes(app.CH2Tab_2);
            title(app.MvDataChannel2, 'Data - Channel 2')
            xlabel(app.MvDataChannel2, 'nSeq')
            ylabel(app.MvDataChannel2, 'mV value')
            zlabel(app.MvDataChannel2, 'Z')
            app.CH3Tab_2 = uitab(app.TabGroup2_2);
            app.CH3Tab_2.Title = 'CH3';
            app.MvDataChannel3 = uiaxes(app.CH3Tab_2);
            title(app.MvDataChannel3, 'Data - Channel 3')
            xlabel(app.MvDataChannel3, 'nSeq')
            ylabel(app.MvDataChannel3, 'mV value')
            zlabel(app.MvDataChannel3, 'Z')
            app.CH4Tab_2 = uitab(app.TabGroup2_2);
            app.CH4Tab_2.Title = 'CH4';
            app.MvDataChannel4 = uiaxes(app.CH4Tab_2);
            title(app.MvDataChannel4, 'Data - Channel 4')
            xlabel(app.MvDataChannel4, 'nSeq')
            ylabel(app.MvDataChannel4, 'mV value')
            zlabel(app.MvDataChannel4, 'Z')
            app.CH5Tab_2 = uitab(app.TabGroup2_2);
            app.CH5Tab_2.Title = 'CH5';
            app.MvDataChannel5 = uiaxes(app.CH5Tab_2);
            title(app.MvDataChannel5, 'Data - Channel 5')
            xlabel(app.MvDataChannel5, 'nSeq')
            ylabel(app.MvDataChannel5, 'mV value')
            zlabel(app.MvDataChannel5, 'Z')
            app.UITable_2 = uitable(app.mVDATATab);
            app.UITable_2.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable_2.RowName = {};
            app.mVDATALabel = uilabel(app.mVDATATab);
            app.mVDATALabel.Visible = 'off';
            app.mVDATALabel.Text = 'mV DATA';
            app.ConverttomVButton = uibutton(app.mVDATATab, 'push');
            app.ConverttomVButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttomVButtonPushed, true);
            app.ConverttomVButton.Enable = 'off';
            app.ConverttomVButton.Text = 'Convert to mV';
            app.FFTANALYSISTab = uitab(app.TabGroup);
            app.FFTANALYSISTab.Title = 'FFT ANALYSIS';
            app.TabGroup2_3 = uitabgroup(app.FFTANALYSISTab);
            app.CH1Tab_3 = uitab(app.TabGroup2_3);
            app.CH1Tab_3.Title = 'CH1';
            app.FFTDataChannel1 = uiaxes(app.CH1Tab_3);
            title(app.FFTDataChannel1, 'Data - Channel 1')
            xlabel(app.FFTDataChannel1, 'Hz')
            ylabel(app.FFTDataChannel1, {'Intensity'; ''})
            zlabel(app.FFTDataChannel1, 'Z')
            app.CH2Tab_3 = uitab(app.TabGroup2_3);
            app.CH2Tab_3.Title = 'CH2';
            app.FFTDataChannel2 = uiaxes(app.CH2Tab_3);
            title(app.FFTDataChannel2, 'Data - Channel 2')
            xlabel(app.FFTDataChannel2, 'Hz')
            ylabel(app.FFTDataChannel2, 'Intensity')
            zlabel(app.FFTDataChannel2, 'Z')
            app.CH3Tab_3 = uitab(app.TabGroup2_3);
            app.CH3Tab_3.Title = 'CH3';
            app.FFTDataChannel3 = uiaxes(app.CH3Tab_3);
            title(app.FFTDataChannel3, 'Data - Channel 3')
            xlabel(app.FFTDataChannel3, 'Hz')
            ylabel(app.FFTDataChannel3, 'Intensity')
            zlabel(app.FFTDataChannel3, 'Z')
            app.CH4Tab_6 = uitab(app.TabGroup2_3);
            app.CH4Tab_6.Title = 'CH4';
            app.FFTDataChannel4 = uiaxes(app.CH4Tab_6);
            title(app.FFTDataChannel4, 'Data - Channel 4')
            xlabel(app.FFTDataChannel4, 'Hz')
            ylabel(app.FFTDataChannel4, 'Intensity')
            zlabel(app.FFTDataChannel4, 'Z')
            app.CH5Tab_3 = uitab(app.TabGroup2_3);
            app.CH5Tab_3.Title = 'CH5';
            app.FFTDataChannel5 = uiaxes(app.CH5Tab_3);
            title(app.FFTDataChannel5, 'Data - Channel 5')
            xlabel(app.FFTDataChannel5, 'Hz')
            ylabel(app.FFTDataChannel5, 'Intensity')
            zlabel(app.FFTDataChannel5, 'Z')
            app.FFTButton = uibutton(app.FFTANALYSISTab, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Enable = 'off';
            app.FFTButton.Text = 'FFT';
            app.REMOVEOUTLIERTab = uitab(app.TabGroup);
            app.REMOVEOUTLIERTab.Title = 'REMOVE OUTLIER';
            app.TabGroup2_4 = uitabgroup(app.REMOVEOUTLIERTab);
            app.CH1TimedomainTab_4 = uitab(app.TabGroup2_4);
            app.CH1TimedomainTab_4.Title = 'CH1 Time domain';
            app.OutlierDataChannel1 = uiaxes(app.CH1TimedomainTab_4);
            title(app.OutlierDataChannel1, 'Data - Channel 1')
            xlabel(app.OutlierDataChannel1, 'X')
            ylabel(app.OutlierDataChannel1, 'Y')
            zlabel(app.OutlierDataChannel1, 'Z')
            app.CH2TimedomainTab_4 = uitab(app.TabGroup2_4);
            app.CH2TimedomainTab_4.Title = 'CH2 Time domain';
            app.OutlierDataChannel2 = uiaxes(app.CH2TimedomainTab_4);
            title(app.OutlierDataChannel2, 'Data - Channel 2')
            xlabel(app.OutlierDataChannel2, 'X')
            ylabel(app.OutlierDataChannel2, 'Y')
            zlabel(app.OutlierDataChannel2, 'Z')
            app.CH3TimedomainTab_4 = uitab(app.TabGroup2_4);
            app.CH3TimedomainTab_4.Title = 'CH3 Time domain';
            app.OutlierDataChannel3 = uiaxes(app.CH3TimedomainTab_4);
            title(app.OutlierDataChannel3, 'Data - Channel 3')
            xlabel(app.OutlierDataChannel3, 'X')
            ylabel(app.OutlierDataChannel3, 'Y')
            zlabel(app.OutlierDataChannel3, 'Z')
            app.CH4TimedomainTab_4 = uitab(app.TabGroup2_4);
            app.CH4TimedomainTab_4.Title = 'CH4 Time domain';
            app.OutlierDataChannel4 = uiaxes(app.CH4TimedomainTab_4);
            title(app.OutlierDataChannel4, 'Data - Channel 4')
            xlabel(app.OutlierDataChannel4, 'X')
            ylabel(app.OutlierDataChannel4, 'Y')
            zlabel(app.OutlierDataChannel4, 'Z')
            app.CH5TimedomainTab_4 = uitab(app.TabGroup2_4);
            app.CH5TimedomainTab_4.Title = 'CH5 Time domain';
            app.OutlierDataChannel5 = uiaxes(app.CH5TimedomainTab_4);
            title(app.OutlierDataChannel5, 'Data - Channel 5')
            xlabel(app.OutlierDataChannel5, 'X')
            ylabel(app.OutlierDataChannel5, 'Y')
            zlabel(app.OutlierDataChannel5, 'Z')
            app.CH1FrequencydomainTab_3 = uitab(app.TabGroup2_4);
            app.CH1FrequencydomainTab_3.Title = 'CH1 Frequency domain';
            app.OutlierDataChannel1_FD = uiaxes(app.CH1FrequencydomainTab_3);
            title(app.OutlierDataChannel1_FD, 'Data - Channel 5')
            xlabel(app.OutlierDataChannel1_FD, 'X')
            ylabel(app.OutlierDataChannel1_FD, 'Y')
            zlabel(app.OutlierDataChannel1_FD, 'Z')
            app.CH2FrequencydomainTab_3 = uitab(app.TabGroup2_4);
            app.CH2FrequencydomainTab_3.Title = 'CH2 Frequency domain';
            app.OutlierDataChannel2_FD = uiaxes(app.CH2FrequencydomainTab_3);
            title(app.OutlierDataChannel2_FD, 'Data - Channel 5')
            xlabel(app.OutlierDataChannel2_FD, 'X')
            ylabel(app.OutlierDataChannel2_FD, 'Y')
            zlabel(app.OutlierDataChannel2_FD, 'Z')
            app.CH3FrequencydomainTab_3 = uitab(app.TabGroup2_4);
            app.CH3FrequencydomainTab_3.Title = 'CH3 Frequency domain';
            app.OutlierDataChannel3_FD = uiaxes(app.CH3FrequencydomainTab_3);
            title(app.OutlierDataChannel3_FD, 'Data - Channel 5')
            xlabel(app.OutlierDataChannel3_FD, 'X')
            ylabel(app.OutlierDataChannel3_FD, 'Y')
            zlabel(app.OutlierDataChannel3_FD, 'Z')
            app.CH4FrequencydomainTab_3 = uitab(app.TabGroup2_4);
            app.CH4FrequencydomainTab_3.Title = 'CH4 Frequency domain';
            app.OutlierDataChannel4_FD = uiaxes(app.CH4FrequencydomainTab_3);
            title(app.OutlierDataChannel4_FD, 'Data - Channel 5')
            xlabel(app.OutlierDataChannel4_FD, 'X')
            ylabel(app.OutlierDataChannel4_FD, 'Y')
            zlabel(app.OutlierDataChannel4_FD, 'Z')
            app.CH5FrequencydomainTab_3 = uitab(app.TabGroup2_4);
            app.CH5FrequencydomainTab_3.Title = 'CH5 Frequency domain';
            app.OutlierDataChannel5_FD = uiaxes(app.CH5FrequencydomainTab_3);
            title(app.OutlierDataChannel5_FD, 'Data - Channel 5')
            xlabel(app.OutlierDataChannel5_FD, 'X')
            ylabel(app.OutlierDataChannel5_FD, 'Y')
            zlabel(app.OutlierDataChannel5_FD, 'Z')
            app.Label_3 = uilabel(app.REMOVEOUTLIERTab);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.REMOVEOUTLIERTab);
            app.Label_4.Text = '';
            app.StandarddeviationLabel = uilabel(app.REMOVEOUTLIERTab);
            app.StandarddeviationLabel.Text = 'Standard deviation:';
            app.OutlieroptionsLabel = uilabel(app.REMOVEOUTLIERTab);
            app.OutlieroptionsLabel.Text = 'Outlier options';
            app.OutlierDeviationOptionDropdown = uidropdown(app.REMOVEOUTLIERTab);
            app.OutlierDeviationOptionDropdown.Items = {'1', '2', '3'};
            app.OutlierDeviationOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @OutlierDeviationOptionDropdownValueChanged, true);
            app.OutlierDeviationOptionDropdown.Value = '1';
            app.RemoveOutlierOptionDropdown = uidropdown(app.REMOVEOUTLIERTab);
            app.RemoveOutlierOptionDropdown.Items = {'median', 'mean', 'quartiles', 'grubbs', 'gesd'};
            app.RemoveOutlierOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @RemoveOutlierOptionDropdownValueChanged, true);
            app.RemoveOutlierOptionDropdown.Value = 'median';
            app.FillOutlierOptionDropdown = uidropdown(app.REMOVEOUTLIERTab);
            app.FillOutlierOptionDropdown.Items = {'center', 'clip', 'previous', 'next', 'nearest', 'linear', 'spline', 'pchip', 'makima'};
            app.FillOutlierOptionDropdown.ValueChangedFcn = createCallbackFcn(app, @FillOutlierOptionDropdownValueChanged, true);
            app.FillOutlierOptionDropdown.Value = 'center';
            app.MethodfordetectingoutliersLabel = uilabel(app.REMOVEOUTLIERTab);
            app.MethodfordetectingoutliersLabel.Text = 'Method for detecting outliers:';
            app.FillmethodLabel = uilabel(app.REMOVEOUTLIERTab);
            app.FillmethodLabel.Text = 'Fill method:';
            app.REMOVEOUTLIERButton = uibutton(app.REMOVEOUTLIERTab, 'push');
            app.REMOVEOUTLIERButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVEOUTLIERButtonPushed, true);
            app.REMOVEOUTLIERButton.Text = 'REMOVE OUTLIER';
            app.HTML2 = uihtml(app.REMOVEOUTLIERTab);
            app.HTML2.HTMLSource = '<h2>Documentation</h2>Percentile thresholds, specified as a two-element row vector whose elements are in the interval [0,100]. The first element indicates the lower percentile threshold and the second element indicates the upper percentile threshold. For example, a threshold of [10 90] defines outliers as points below the 10th percentile and above the 90th percentile. The first element of threshold must be less than the second element. \n\nMethod for detecting outliers, specified as one of the following:</br><ul><li><b>Median:</b> Outliers are defined as elements more than three scaled MAD from the median. The scaled MAD is defined as c*median(abs(A-median(A))), where c=-1/(sqrt(2)*erfcinv(3/2)).</li><li><b>Mean:</b> Outliers are defined as elements more than three standard deviations from the mean. This method is faster but less robust than ''median''.</li><li><b>Quartiles:</b> Outliers are defined as elements more than 1.5 interquartile ranges above the upper quartile (75 percent) or below the lower quartile (25 percent). This method is useful when the data in A is not normally distributed.</li><li><b>Grubbs:</b>Outliers are detected using Grubbs’s test, which removes one outlier per iteration based on hypothesis testing. This method assumes that the data in A is normally distributed.</li><li><b>Gesd:</b>Outliers are detected using the generalized extreme Studentized deviate test for outliers. This iterative method is similar to ''grubbs'', but can perform better when there are multiple outliers masking each other.</li></ul>Fill method for replacing outliers, specified as a numeric scalar or one of the following:<ul><li><b>Center:</b>Fills with the center value determined by findmethod.</li><li><b>Clip:</b>Fills with the lower threshold value for elements smaller than the lower threshold determined by findmethod. Fills with the upper threshold value for elements larger than the upper threshold determined by findmethod.</li><li><b>Previous:</b>Fills with the previous non-outlier value.</li><li><b>Next:</b>Fills with the next non-outlier value.</li><li><b>Nearest:</b>Fills with the nearest non-outlier value.</li><li><b>Linear:</b>Fills using linear interpolation of neighboring, non-outlier values.</li><li><b>Spline:</b>Fills using piecewise cubic spline interpolation.</li><li><b>Pchip:</b>Fills using shape-preserving piecewise cubic spline interpolation.</li><li><b>Makima:</b>Modified Akima cubic Hermite interpolation (numeric, duration, and datetime data types only).</li></ul>';
            app.PROCESSINGTRACKATab = uitab(app.TabGroup);
            app.PROCESSINGTRACKATab.Title = 'PROCESSING TRACK A';
            app.TabGroup2_5 = uitabgroup(app.PROCESSINGTRACKATab);
            app.CH1TimedomainTab = uitab(app.TabGroup2_5);
            app.CH1TimedomainTab.Title = 'CH1 Time domain';
            app.TrackAchannel1 = uiaxes(app.CH1TimedomainTab);
            title(app.TrackAchannel1, 'Data - Channel 1')
            xlabel(app.TrackAchannel1, 'nSeq')
            ylabel(app.TrackAchannel1, 'mV')
            zlabel(app.TrackAchannel1, 'Z')
            app.CH2TimedomainTab = uitab(app.TabGroup2_5);
            app.CH2TimedomainTab.Title = 'CH2 Time domain';
            app.TrackAchannel2 = uiaxes(app.CH2TimedomainTab);
            title(app.TrackAchannel2, 'Data - Channel 2')
            xlabel(app.TrackAchannel2, 'nSeq')
            ylabel(app.TrackAchannel2, 'mV')
            zlabel(app.TrackAchannel2, 'Z')
            app.CH3TimedomainTab_3 = uitab(app.TabGroup2_5);
            app.CH3TimedomainTab_3.Title = 'CH3 Time domain';
            app.TrackAchannel3 = uiaxes(app.CH3TimedomainTab_3);
            title(app.TrackAchannel3, 'Data - Channel 3')
            xlabel(app.TrackAchannel3, 'nSeq')
            ylabel(app.TrackAchannel3, 'mV')
            zlabel(app.TrackAchannel3, 'Z')
            app.CH4TimedomainTab = uitab(app.TabGroup2_5);
            app.CH4TimedomainTab.Title = 'CH4 Time domain';
            app.TrackAchannel4 = uiaxes(app.CH4TimedomainTab);
            title(app.TrackAchannel4, 'Data - Channel 4')
            xlabel(app.TrackAchannel4, 'nSeq')
            ylabel(app.TrackAchannel4, 'mV')
            zlabel(app.TrackAchannel4, 'Z')
            app.CH5TimedomainTab = uitab(app.TabGroup2_5);
            app.CH5TimedomainTab.Title = 'CH5 Time domain';
            app.TrackAchannel5 = uiaxes(app.CH5TimedomainTab);
            title(app.TrackAchannel5, 'Data - Channel 5')
            xlabel(app.TrackAchannel5, 'nSeq')
            ylabel(app.TrackAchannel5, 'mV')
            zlabel(app.TrackAchannel5, 'Z')
            app.CH1FrequencydomainTab = uitab(app.TabGroup2_5);
            app.CH1FrequencydomainTab.Title = 'CH1 Frequency domain';
            app.TrackAchannel1FD = uiaxes(app.CH1FrequencydomainTab);
            title(app.TrackAchannel1FD, 'Data - Channel 1')
            xlabel(app.TrackAchannel1FD, 'Hz')
            ylabel(app.TrackAchannel1FD, 'Intensity')
            zlabel(app.TrackAchannel1FD, 'Z')
            app.CH2FrequencydomainTab = uitab(app.TabGroup2_5);
            app.CH2FrequencydomainTab.Title = 'CH2 Frequency domain';
            app.TrackAchannel2FD = uiaxes(app.CH2FrequencydomainTab);
            title(app.TrackAchannel2FD, 'Data - Channel 2')
            xlabel(app.TrackAchannel2FD, 'Hz')
            ylabel(app.TrackAchannel2FD, 'Intensity')
            zlabel(app.TrackAchannel2FD, 'Z')
            app.CH3FrequencydomainTab = uitab(app.TabGroup2_5);
            app.CH3FrequencydomainTab.Title = 'CH3 Frequency domain';
            app.TrackAchannel3FD = uiaxes(app.CH3FrequencydomainTab);
            title(app.TrackAchannel3FD, 'Data - Channel 3')
            xlabel(app.TrackAchannel3FD, 'Hz')
            ylabel(app.TrackAchannel3FD, 'Intensity')
            zlabel(app.TrackAchannel3FD, 'Z')
            app.CH4FrequencydomainTab = uitab(app.TabGroup2_5);
            app.CH4FrequencydomainTab.Title = 'CH4 Frequency domain';
            app.TrackAchannel4FD = uiaxes(app.CH4FrequencydomainTab);
            title(app.TrackAchannel4FD, 'Data - Channel 4')
            xlabel(app.TrackAchannel4FD, 'Hz')
            ylabel(app.TrackAchannel4FD, 'Intensity')
            zlabel(app.TrackAchannel4FD, 'Z')
            app.CH5FrequencydomainTab = uitab(app.TabGroup2_5);
            app.CH5FrequencydomainTab.Title = 'CH5 Frequency domain';
            app.TrackAchannel5FD = uiaxes(app.CH5FrequencydomainTab);
            title(app.TrackAchannel5FD, 'Data - Channel 5')
            xlabel(app.TrackAchannel5FD, 'Hz')
            ylabel(app.TrackAchannel5FD, 'Intensity')
            zlabel(app.TrackAchannel5FD, 'Z')
            app.Label_5 = uilabel(app.PROCESSINGTRACKATab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.PROCESSINGTRACKATab);
            app.Label_6.Text = '';
            app.FilteroptionsLabel = uilabel(app.PROCESSINGTRACKATab);
            app.FilteroptionsLabel.Text = 'Filter options';
            app.FiltertypeLabel = uilabel(app.PROCESSINGTRACKATab);
            app.FiltertypeLabel.Text = 'Filter type:';
            app.ApplyfilterButton = uibutton(app.PROCESSINGTRACKATab, 'push');
            app.ApplyfilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyfilterButtonPushed, true);
            app.ApplyfilterButton.Text = 'Apply filter';
            app.FilterTypeDropdown = uidropdown(app.PROCESSINGTRACKATab);
            app.FilterTypeDropdown.Items = {'High-pass', 'Low-pass', 'Band-pass', 'Bandstop'};
            app.FilterTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropdownValueChanged, true);
            app.FilterTypeDropdown.Placeholder = 'Select filter';
            app.FilterTypeDropdown.Value = 'High-pass';
            app.FilterOrderDropdown = uidropdown(app.PROCESSINGTRACKATab);
            app.FilterOrderDropdown.Items = {'1', '2', '3', '4', '5'};
            app.FilterOrderDropdown.ValueChangedFcn = createCallbackFcn(app, @FilterOrderDropdownValueChanged, true);
            app.FilterOrderDropdown.Value = '1';
            app.FilterorderLabel = uilabel(app.PROCESSINGTRACKATab);
            app.FilterorderLabel.Text = 'Filter order:';
            app.TrackA_FC2 = uieditfield(app.PROCESSINGTRACKATab, 'numeric');
            app.TrackA_FC2.Limits = [1 249];
            app.TrackA_FC2.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC2ValueChanged, true);
            app.TrackA_FC2.Visible = 'off';
            app.TrackA_FC2.Value = 1;
            app.TrackA_FC1 = uieditfield(app.PROCESSINGTRACKATab, 'numeric');
            app.TrackA_FC1.Limits = [1 249];
            app.TrackA_FC1.ValueChangedFcn = createCallbackFcn(app, @TrackA_FC1ValueChanged, true);
            app.TrackA_FC1.Value = 1;
            app.Cuttofffrequency1Label = uilabel(app.PROCESSINGTRACKATab);
            app.Cuttofffrequency1Label.Text = 'Cuttoff frequency 1:';
            app.Cuttofffrequency2Label = uilabel(app.PROCESSINGTRACKATab);
            app.Cuttofffrequency2Label.Visible = 'off';
            app.Cuttofffrequency2Label.Text = 'Cuttoff frequency 2:';
            app.ExportTrackA = uibutton(app.PROCESSINGTRACKATab, 'push');
            app.ExportTrackA.ButtonPushedFcn = createCallbackFcn(app, @ExportTrackAPushed, true);
            app.ExportTrackA.Text = {'Export'; ''};
            app.HTML3 = uihtml(app.PROCESSINGTRACKATab);
            app.HTML3.HTMLSource = '{''<h2>Documentation</h2>Filters 1D signal using a specified filtertype and cutoff frequency Fc. For high-pass orlow-pass filters Fc must be a scalar. For band-pass and band-stop filters Fc must be a two-element array.<br>The filtertype can be: <ul>    <li><b>High-pass</b> with scalar cutoff frequency Fc.</li>    <li><b>Low-pass</b> with scalar cutoff frequency Fc.</li>    <li><b>Band-pass</b> with two-element cutoff frequencies Fc.</li>    <li><b>Band-stop</b> with two-element cutoff frequencies Fc.</li></ul>''}';
            app.PROCESSINGTRACKBTab = uitab(app.TabGroup);
            app.PROCESSINGTRACKBTab.Title = 'PROCESSING TRACK B';
            app.Label_8 = uilabel(app.PROCESSINGTRACKBTab);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.PROCESSINGTRACKBTab);
            app.Label_9.Text = '';
            app.TrackBoptionsLabel = uilabel(app.PROCESSINGTRACKBTab);
            app.TrackBoptionsLabel.Text = 'Track B options';
            app.WindowSizeTextbox = uieditfield(app.PROCESSINGTRACKBTab, 'numeric');
            app.WindowSizeTextbox.Limits = [1 Inf];
            app.WindowSizeTextbox.ValueChangedFcn = createCallbackFcn(app, @WindowSizeTextboxValueChanged, true);
            app.WindowSizeTextbox.Value = 1;
            app.WindowsizeLabel = uilabel(app.PROCESSINGTRACKBTab);
            app.WindowsizeLabel.Text = 'Window size:';
            app.TabGroup2_6 = uitabgroup(app.PROCESSINGTRACKBTab);
            app.CH1TimedomainTab_2 = uitab(app.TabGroup2_6);
            app.CH1TimedomainTab_2.Title = 'CH1 Time domain';
            app.TrackBchannel1_2 = uiaxes(app.CH1TimedomainTab_2);
            title(app.TrackBchannel1_2, 'Data - Channel 1')
            xlabel(app.TrackBchannel1_2, 'nSeq')
            ylabel(app.TrackBchannel1_2, 'mV')
            zlabel(app.TrackBchannel1_2, 'Z')
            app.CH2TimedomainTab_2 = uitab(app.TabGroup2_6);
            app.CH2TimedomainTab_2.Title = 'CH2 Time domain';
            app.TrackBchannel2_2 = uiaxes(app.CH2TimedomainTab_2);
            title(app.TrackBchannel2_2, 'Data - Channel 2')
            xlabel(app.TrackBchannel2_2, 'nSeq')
            ylabel(app.TrackBchannel2_2, 'mV')
            app.CH3TimedomainTab = uitab(app.TabGroup2_6);
            app.CH3TimedomainTab.Title = 'CH3 Time domain';
            app.TrackBchannel3_2 = uiaxes(app.CH3TimedomainTab);
            title(app.TrackBchannel3_2, 'Data - Channel 3')
            xlabel(app.TrackBchannel3_2, 'nSeq')
            ylabel(app.TrackBchannel3_2, 'mV')
            app.CH4TimedomainTab_2 = uitab(app.TabGroup2_6);
            app.CH4TimedomainTab_2.Title = 'CH4 Time domain';
            app.TrackBchannel4_2 = uiaxes(app.CH4TimedomainTab_2);
            title(app.TrackBchannel4_2, 'Data - Channel 4')
            xlabel(app.TrackBchannel4_2, 'nSeq')
            ylabel(app.TrackBchannel4_2, 'mV')
            zlabel(app.TrackBchannel4_2, 'Z')
            app.CH5TimedomainTab_2 = uitab(app.TabGroup2_6);
            app.CH5TimedomainTab_2.Title = 'CH5 Time domain';
            app.TrackBchannel5_2 = uiaxes(app.CH5TimedomainTab_2);
            title(app.TrackBchannel5_2, 'Data - Channel 5')
            xlabel(app.TrackBchannel5_2, 'nSeq')
            ylabel(app.TrackBchannel5_2, 'mV')
            zlabel(app.TrackBchannel5_2, 'Z')
            app.CH1FrequencydomainTab_2 = uitab(app.TabGroup2_6);
            app.CH1FrequencydomainTab_2.Title = 'CH1 Frequency domain';
            app.TrackBchannel1_FD = uiaxes(app.CH1FrequencydomainTab_2);
            title(app.TrackBchannel1_FD, 'Data - Channel 1')
            xlabel(app.TrackBchannel1_FD, 'Hz')
            ylabel(app.TrackBchannel1_FD, 'Intensity')
            zlabel(app.TrackBchannel1_FD, 'Z')
            app.CH2FrequencydomainTab_2 = uitab(app.TabGroup2_6);
            app.CH2FrequencydomainTab_2.Title = 'CH2 Frequency domain';
            app.TrackBchannel2_FD = uiaxes(app.CH2FrequencydomainTab_2);
            title(app.TrackBchannel2_FD, 'Data - Channel 2')
            xlabel(app.TrackBchannel2_FD, 'Hz')
            ylabel(app.TrackBchannel2_FD, 'Intensity')
            zlabel(app.TrackBchannel2_FD, 'Z')
            app.CH3FrequencydomainTab_2 = uitab(app.TabGroup2_6);
            app.CH3FrequencydomainTab_2.Title = 'CH3 Frequency domain';
            app.TrackBchannel3_FD = uiaxes(app.CH3FrequencydomainTab_2);
            title(app.TrackBchannel3_FD, 'Data - Channel 3')
            xlabel(app.TrackBchannel3_FD, 'Hz')
            ylabel(app.TrackBchannel3_FD, 'Intensity')
            zlabel(app.TrackBchannel3_FD, 'Z')
            app.CH4FrequencydomainTab_2 = uitab(app.TabGroup2_6);
            app.CH4FrequencydomainTab_2.Title = 'CH4 Frequency domain';
            app.TrackBchannel4_FD = uiaxes(app.CH4FrequencydomainTab_2);
            title(app.TrackBchannel4_FD, 'Data - Channel 4')
            xlabel(app.TrackBchannel4_FD, 'Hz')
            ylabel(app.TrackBchannel4_FD, 'Intensity')
            zlabel(app.TrackBchannel4_FD, 'Z')
            app.CH5FrequencydomainTab_2 = uitab(app.TabGroup2_6);
            app.CH5FrequencydomainTab_2.Title = 'CH5 Frequency domain';
            app.TrackBchannel5_FD = uiaxes(app.CH5FrequencydomainTab_2);
            title(app.TrackBchannel5_FD, 'Data - Channel 5')
            xlabel(app.TrackBchannel5_FD, 'Hz')
            ylabel(app.TrackBchannel5_FD, 'Intensity')
            zlabel(app.TrackBchannel5_FD, 'Z')
            app.ApplyButton = uibutton(app.PROCESSINGTRACKBTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = {'Apply'; ''};
            app.HTML2_2 = uihtml(app.PROCESSINGTRACKBTab);
            app.HTML2_2.HTMLSource = '{''<h2>Documentation</h2>RMS is the root-mean-square value of a signal. For a digitised signal, you can calculate it by squaring each value, finding the arithmetic mean of those squared values, and taking the square root of the result.Loosely speaking, it represents the average "power" of a signal. In electrical terms, all signals with the same RMS voltage, when applied across a resistor, will produce the same amount of heat.<br>The options are: <ul>    <li><b>Window size:</b> The amount of values it uses to calculate each time.</li> </ul>''}';
            app.ExportTrackB = uibutton(app.PROCESSINGTRACKBTab, 'push');
            app.ExportTrackB.ButtonPushedFcn = createCallbackFcn(app, @ExportTrackBButtonPushed, true);
            app.ExportTrackB.Text = {'Export'; ''};
            app.MVCNORMALISATIONTab = uitab(app.TabGroup);
            app.MVCNORMALISATIONTab.Title = 'MVC NORMALISATION';
            app.MVCNORMALISATIONButton = uibutton(app.MVCNORMALISATIONTab, 'push');
            app.MVCNORMALISATIONButton.ButtonPushedFcn = createCallbackFcn(app, @MVCNORMALISATIONButtonPushed, true);
            app.MVCNORMALISATIONButton.Enable = 'off';
            app.MVCNORMALISATIONButton.Text = 'MVC NORMALISATION';
            app.ExportButton = uibutton(app.MVCNORMALISATIONTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TabGroup2_7 = uitabgroup(app.MVCNORMALISATIONTab);
            app.CH1TimedomainTab_3 = uitab(app.TabGroup2_7);
            app.CH1TimedomainTab_3.Title = 'CH 1 Time domain';
            app.MVCnormalisationChannel1 = uiaxes(app.CH1TimedomainTab_3);
            title(app.MVCnormalisationChannel1, 'Data - Channel 1')
            xlabel(app.MVCnormalisationChannel1, 'X')
            ylabel(app.MVCnormalisationChannel1, 'Y')
            zlabel(app.MVCnormalisationChannel1, 'Z')
            app.MVCnormalisationChannel1.YLim = [0 1];
            app.CH2TimedomainTab_3 = uitab(app.TabGroup2_7);
            app.CH2TimedomainTab_3.Title = 'CH2 Time domain';
            app.MVCnormalisationChannel2 = uiaxes(app.CH2TimedomainTab_3);
            title(app.MVCnormalisationChannel2, 'Data - Channel 2')
            xlabel(app.MVCnormalisationChannel2, 'X')
            ylabel(app.MVCnormalisationChannel2, 'Y')
            zlabel(app.MVCnormalisationChannel2, 'Z')
            app.MVCnormalisationChannel2.YLim = [0 1];
            app.CH3TimedomainTab_2 = uitab(app.TabGroup2_7);
            app.CH3TimedomainTab_2.Title = 'CH3 Time domain';
            app.MVCnormalisationChannel3 = uiaxes(app.CH3TimedomainTab_2);
            title(app.MVCnormalisationChannel3, 'Data - Channel 3')
            xlabel(app.MVCnormalisationChannel3, 'X')
            ylabel(app.MVCnormalisationChannel3, 'Y')
            zlabel(app.MVCnormalisationChannel3, 'Z')
            app.MVCnormalisationChannel3.YLim = [0 1];
            app.CH4TimedomainTab_3 = uitab(app.TabGroup2_7);
            app.CH4TimedomainTab_3.Title = 'CH4 Time domain';
            app.MVCnormalisationChannel4 = uiaxes(app.CH4TimedomainTab_3);
            title(app.MVCnormalisationChannel4, 'Data - Channel 4')
            xlabel(app.MVCnormalisationChannel4, 'X')
            ylabel(app.MVCnormalisationChannel4, 'Y')
            zlabel(app.MVCnormalisationChannel4, 'Z')
            app.MVCnormalisationChannel4.YLim = [0 1];
            app.CH5TimedomainTab_3 = uitab(app.TabGroup2_7);
            app.CH5TimedomainTab_3.Title = 'CH 5 Time domain';
            app.MVCnormalisationChannel5 = uiaxes(app.CH5TimedomainTab_3);
            title(app.MVCnormalisationChannel5, 'Data - Channel 5')
            xlabel(app.MVCnormalisationChannel5, 'X')
            ylabel(app.MVCnormalisationChannel5, 'Y')
            zlabel(app.MVCnormalisationChannel5, 'Z')
            app.MVCnormalisationChannel5.YLim = [0 1];
            app.UITableMVC = uitable(app.MVCNORMALISATIONTab);
            app.UITableMVC.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITableMVC.RowName = {};
            app.YourdataMVC = uilabel(app.MVCNORMALISATIONTab);
            app.YourdataMVC.Visible = 'off';
            app.YourdataMVC.Text = 'Your data';
            app.DropDownMVC = uidropdown(app.MVCNORMALISATIONTab);
            app.DropDownMVC.Items = {'Track A Filter', 'Track B RMS'};
            app.DropDownMVC.ValueChangedFcn = createCallbackFcn(app, @DropDownMVCValueChanged, true);
            app.DropDownMVC.Enable = 'off';
            app.DropDownMVC.Value = 'Track A Filter';
            app.SELECTMVCFILESButton = uibutton(app.MVCNORMALISATIONTab, 'push');
            app.SELECTMVCFILESButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTMVCFILESButtonPushed, true);
            app.SELECTMVCFILESButton.Text = 'SELECT MVC FILES';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.ImportdataButton = uitogglebutton(app.ButtonGroup);
            app.ImportdataButton.Text = 'Import data';
            app.ImportdataButton.Value = true;
            app.ConversionButton = uitogglebutton(app.ButtonGroup);
            app.ConversionButton.Enable = 'off';
            app.ConversionButton.Text = 'Conversion';
            app.FastFourrierButton = uitogglebutton(app.ButtonGroup);
            app.FastFourrierButton.Enable = 'off';
            app.FastFourrierButton.Text = 'Fast Fourrier';
            app.OutlierremovalButton = uitogglebutton(app.ButtonGroup);
            app.OutlierremovalButton.Enable = 'off';
            app.OutlierremovalButton.Text = 'Outlier removal';
            app.TrackAButton = uitogglebutton(app.ButtonGroup);
            app.TrackAButton.Enable = 'off';
            app.TrackAButton.Text = 'Track A';
            app.TrackBButton = uitogglebutton(app.ButtonGroup);
            app.TrackBButton.Enable = 'off';
            app.TrackBButton.Text = 'Track B';
            app.ProcessingLabel = uilabel(app.ButtonGroup);
            app.ProcessingLabel.Text = 'Processing';
            app.imageImport = uiimage(app.ButtonGroup);
            app.imageImport.ImageSource = 'import.png';
            app.ImageConversion = uiimage(app.ButtonGroup);
            app.ImageConversion.Enable = 'off';
            app.ImageConversion.ImageSource = 'convert.png';
            app.ImageFastFourrier = uiimage(app.ButtonGroup);
            app.ImageFastFourrier.Enable = 'off';
            app.ImageFastFourrier.ImageSource = 'frequency.png';
            app.ImageOutlier = uiimage(app.ButtonGroup);
            app.ImageOutlier.Enable = 'off';
            app.ImageOutlier.ImageSource = 'remove.png';
            app.ImageA = uiimage(app.ButtonGroup);
            app.ImageA.Enable = 'off';
            app.ImageA.ImageSource = 'A.png';
            app.ImageB = uiimage(app.ButtonGroup);
            app.ImageB.Enable = 'off';
            app.ImageB.ImageSource = 'B.png';
            app.NormalisationLabel = uilabel(app.ButtonGroup);
            app.NormalisationLabel.Text = 'Normalisation';
            app.MVCNormalisationButton = uitogglebutton(app.ButtonGroup);
            app.MVCNormalisationButton.Enable = 'off';
            app.MVCNormalisationButton.Text = 'MVC Normalisation';
            app.MVCImage = uiimage(app.ButtonGroup);
            app.MVCImage.Enable = 'off';
            app.MVCImage.ImageSource = 'line-graph.png';
            app.sEMGANALYSISTOOLBOXLabel = uilabel(app.UIFigure);
            app.sEMGANALYSISTOOLBOXLabel.Text = 'sEMG ANALYSIS TOOLBOX';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {''; ''};
            app.ExitImage = uiimage(app.UIFigure);
            app.ExitImage.ImageClickedFcn = createCallbackFcn(app, @ExitImageClicked, true);
            app.ExitImage.ImageSource = 'exit.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end